classdef imgediter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        outputImageLabel2  matlab.ui.control.Label
        outputImage2       matlab.ui.control.Image
        gammaLabel         matlab.ui.control.Label
        cLabel             matlab.ui.control.Label
        hasilLabel         matlab.ui.control.Label
        outputImageLabel   matlab.ui.control.Label
        outputImage        matlab.ui.control.Image
        soalMenu           matlab.ui.container.ButtonGroup
        soal4Button        matlab.ui.control.RadioButton
        soal3Button        matlab.ui.control.RadioButton
        soal2Button        matlab.ui.control.RadioButton
        soal1Button        matlab.ui.control.RadioButton
        subSoal2Menu       matlab.ui.container.ButtonGroup
        soal2eButton       matlab.ui.control.RadioButton
        soal2dButton       matlab.ui.control.RadioButton
        soal2cButton       matlab.ui.control.RadioButton
        soal2bButton       matlab.ui.control.RadioButton
        soal2aButton       matlab.ui.control.RadioButton
        gammaEditField     matlab.ui.control.NumericEditField
        cEditField         matlab.ui.control.NumericEditField
        aEditField         matlab.ui.control.NumericEditField
        aLabel             matlab.ui.control.Label
        bEditField         matlab.ui.control.NumericEditField
        bEditFieldLabel    matlab.ui.control.Label
        InputImageLabel    matlab.ui.control.Label
        appTitle           matlab.ui.control.Label
        inputImage         matlab.ui.control.Image
        prosesButton       matlab.ui.control.Button
        browseButton       matlab.ui.control.Button
        inputimgfield      matlab.ui.control.EditField
        outputHist2        matlab.ui.control.UIAxes
        outputHist         matlab.ui.control.UIAxes
        inputHist          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputPath % Description
        InputImageData 
    end
    
    methods (Access = private)
        
         function plotCustomHistogram(app, targetAxes, imageData)
          
            if ~isvalid(targetAxes)
                return;
            end
            
            if isempty(imageData)
                return;
            end
            
            if size(imageData, 3) == 3
                imageData = rgb2gray(imageData);
                
                % Opsi 2 (jika ingin 3 plot): Anda perlu memodifikasi fungsi ini
                % untuk menerima R, G, B dan memplotnya dengan warna berbeda.
                % Untuk saat ini, kita buat sederhana dengan grayscale.
            end
            
            % Panggil fungsi kustom Anda untuk menghitung data histogram
            counts = custom_image_histogram(imageData);
            
            % Tampilkan data histogram menggunakan bar plot
            bar(targetAxes, 0:255, counts, 'FaceColor', 'k');
            
            % Atur properti axes
            grid(targetAxes, 'on');
            xlabel(targetAxes, 'Intensitas');
            ylabel(targetAxes, 'Jumlah Piksel');
            xlim(targetAxes, [0 255]);
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(fullfile(pwd, 'soal2'));
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg','Image Files (*.jpg,*.png,*.jpeg)'},'Pilih Gambar');
            if isequal(file,0)
                disp('User batal memilih file');
            else
               
                app.inputPath = fullfile(path, file);
                print(app.inputPath)
                app.InputImageData = imread(app.inputPath);
                imgname = file;
                app.inputimgfield.Value = imgname;
                app.inputImage.ImageSource = app.InputImageData;

                 cla(app.inputHist, 'reset');
                % Panggil fungsi pembantu untuk plot histogram
                custom_image_histogram(app.InputImageData);
                title(app.inputHist, 'Input Histogram');
                
                imgData = imread(app.inputImage.ImageSource);
                histData = imhist(imgData);
                plot(app.inputHist, histData);
                title(app.inputHist, 'Input Histogram'); 
                app.inputImage.Visible = 'on';
                app.InputImageLabel.Visible = 'on';
                app.inputHist.Visible = 'on';
                
                app.outputImage.Visible = 'off';
                app.outputImageLabel.Visible = 'off';
                app.outputHist.Visible = 'off';
                
                app.outputImage2.Visible = 'off';
                app.outputImageLabel2.Visible = 'off';
                app.outputHist2.Visible = 'off';
                % --- AKHIR DARI KODE TAMBAHAN ---
            end
        end

        % Button pushed function: prosesButton
        function prosesButtonPushed(app, event)
            app.outputImage.ImageSource = '';
            app.outputImage2.ImageSource = '';
            cla(app.outputHist2, 'reset'); % Cara benar untuk membersihkan UIAxes
            cla(app.outputHist, 'reset'); % Cara benar untuk membersihkan UIAxes
            imgInp = app.inputPath;

            if ~isempty(imgInp)
                choice  = app.soalMenu.SelectedObject;

                if ~isempty(choice)

                    switch choice
                        case app.soal2Button
                            choicesubsoal = app.subSoal2Menu.SelectedObject;

                            if ~isempty (choicesubsoal)
                                switch choicesubsoal
                                    case app.soal2aButton
                                        brightener(app, imgInp, app.aEditField.Value, app.bEditField.Value );
                                    case app.soal2bButton
                                        negative(app, imgInp);
                                     case app.soal2cButton
                                        logTransform (app,imgInp, app.cEditField.Value)
                                    case app.soal2dButton
                                        powerTransform(app,imgInp, app.cEditField.Value, app.gammaEditField.Value)
                                    case app.soal2eButton
                                        contrastStrecth(app, imgInp)
                                end
                            end

                    end
                end
            end    
        end

        % Selection changed function: soalMenu
        function soalChoice(app, event)
            choice = app.soalMenu.SelectedObject;

            app.subSoal2Menu.Visible = 'off';
            app.aEditField.Visible = 'off';
            app.bEditField.Visible = 'off';
            app.cEditField.Visible = 'off';
            app.gammaEditField.Visible = 'off';
        
            % --- LANGKAH 2: ATUR STATE BARU SESUAI PILIHAN ---
            switch choice
                case app.soal1Button
                    % Tidak ada yang perlu ditampilkan secara khusus, 
                    % kondisi reset di atas sudah cukup.
                    % Mungkin Anda ingin menampilkan panel lain di sini.
                    
                case app.soal2Button
                    % Untuk soal 2, tampilkan sub-menu DAN state defaultnya.
                    app.subSoal2Menu.Visible = 'on';
                   
                    app.soal2aButton.Value = true;
                    
                    app.aEditField.Visible = 'on';
                    app.bEditField.Visible = 'on';
                    
        
                case app.soal3Button
                    
                    
                 case app.soal4Button  

            end
       

        end

        % Selection changed function: subSoal2Menu
        function soal2Choice(app, event)
            choice = app.subSoal2Menu.SelectedObject;
        
            % LANGKAH 1: RESET STATE
            app.aEditField.Visible = 'off';
            app.bEditField.Visible = 'off';
            app.cEditField.Visible = 'off';
            app.gammaEditField.Visible = 'off';
        
            % LANGKAH 2: ATUR STATE BARU DENGAN SWITCH
            switch choice
                case app.soal2aButton
                    app.aEditField.Visible = 'on';
                    app.bEditField.Visible = 'on';

                case app.soal2bButton
                    % Tidak perlu melakukan apa-apa
        
                case app.soal2cButton
                    app.cEditField.Visible = 'on';

        
                case app.soal2dButton
                    app.cEditField.Visible = 'on';
                    app.gammaEditField.Visible = 'on';
                
                case app.soal2eButton
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.inputHist = uiaxes(app.UIFigure);
            title(app.inputHist, 'Input Histogram')
            xlabel(app.inputHist, 'X')
            ylabel(app.inputHist, 'Y')
            zlabel(app.inputHist, 'Z')
            app.inputHist.Visible = 'off';
            app.outputHist = uiaxes(app.UIFigure);
            title(app.outputHist, 'Output Histogram')
            xlabel(app.outputHist, 'X')
            ylabel(app.outputHist, 'Y')
            zlabel(app.outputHist, 'Z')
            app.outputHist.Visible = 'off';
            app.outputHist2 = uiaxes(app.UIFigure);
            title(app.outputHist2, 'Output Histogram 2')
            xlabel(app.outputHist2, 'X')
            ylabel(app.outputHist2, 'Y')
            zlabel(app.outputHist2, 'Z')
            app.outputHist2.Visible = 'off';
            app.inputimgfield = uieditfield(app.UIFigure, 'text');
            app.inputimgfield.Value = 'Gambar Input';
            app.browseButton = uibutton(app.UIFigure, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.prosesButton = uibutton(app.UIFigure, 'push');
            app.prosesButton.ButtonPushedFcn = createCallbackFcn(app, @prosesButtonPushed, true);
            app.prosesButton.Text = 'proses';
            app.inputImage = uiimage(app.UIFigure);
            app.inputImage.Visible = 'off';
            app.appTitle = uilabel(app.UIFigure);
            app.appTitle.Text = 'IMAGE EDITOR';
            app.InputImageLabel = uilabel(app.UIFigure);
            app.InputImageLabel.Visible = 'off';
            app.InputImageLabel.Text = 'Input Image';
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Visible = 'off';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Visible = 'off';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.Visible = 'off';
            app.gammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.gammaEditField.Visible = 'off';
            app.subSoal2Menu = uibuttongroup(app.UIFigure);
            app.subSoal2Menu.SelectionChangedFcn = createCallbackFcn(app, @soal2Choice, true);
            app.subSoal2Menu.Visible = 'off';
            app.soal2aButton = uiradiobutton(app.subSoal2Menu);
            app.soal2aButton.Text = 'Soal 2A';
            app.soal2aButton.Value = true;
            app.soal2bButton = uiradiobutton(app.subSoal2Menu);
            app.soal2bButton.Text = 'Soal 2B';
            app.soal2cButton = uiradiobutton(app.subSoal2Menu);
            app.soal2cButton.Text = 'Soal 2C';
            app.soal2dButton = uiradiobutton(app.subSoal2Menu);
            app.soal2dButton.Text = 'Soal 2D';
            app.soal2eButton = uiradiobutton(app.subSoal2Menu);
            app.soal2eButton.Text = 'Soal 2E';
            app.soalMenu = uibuttongroup(app.UIFigure);
            app.soalMenu.SelectionChangedFcn = createCallbackFcn(app, @soalChoice, true);
            app.soal1Button = uiradiobutton(app.soalMenu);
            app.soal1Button.Text = 'Soal 1';
            app.soal1Button.Value = true;
            app.soal2Button = uiradiobutton(app.soalMenu);
            app.soal2Button.Text = 'Soal 2';
            app.soal3Button = uiradiobutton(app.soalMenu);
            app.soal3Button.Text = 'Soal 3';
            app.soal4Button = uiradiobutton(app.soalMenu);
            app.soal4Button.Text = 'Soal 4';
            app.outputImage = uiimage(app.UIFigure);
            app.outputImage.Visible = 'off';
            app.outputImageLabel = uilabel(app.UIFigure);
            app.outputImageLabel.Visible = 'off';
            app.outputImageLabel.Text = 'Output Image';
            app.hasilLabel = uilabel(app.UIFigure);
            app.hasilLabel.Text = 'HASIL';
            app.cLabel = uilabel(app.UIFigure);
            app.cLabel.Text = 'c';
            app.gammaLabel = uilabel(app.UIFigure);
            app.gammaLabel.Text = 'gamma';
            app.outputImage2 = uiimage(app.UIFigure);
            app.outputImage2.Visible = 'off';
            app.outputImageLabel2 = uilabel(app.UIFigure);
            app.outputImageLabel2.Visible = 'off';
            app.outputImageLabel2.Text = 'Output Image 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgediter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end